classdef UAVHybridFix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SISTEMPENGATURANDANPEMANDUANUAVHYBRIDLabel  matlab.ui.control.Label
        LABORATORIUMSISTEMDANSIBERNETIKALabel  matlab.ui.control.Label
        PilihDropDownLabel          matlab.ui.control.Label
        PilihDropDown               matlab.ui.control.DropDown
        Text_Pendahuluan            matlab.ui.control.Label
        Text_Spesifikasi            matlab.ui.control.Label
        Text_Persamaan              matlab.ui.control.Label
        Grafik                      matlab.ui.control.UIAxes
        MulaiAll                    matlab.ui.control.Button
        Text_Penjelasan_Ketinggian  matlab.ui.control.Label
        PengaturanKetinggian        matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        KPSpinnerLabel              matlab.ui.control.Label
        KPSpinner                   matlab.ui.control.Spinner
        KISpinnerLabel              matlab.ui.control.Label
        KISpinner                   matlab.ui.control.Spinner
        KDSpinnerLabel              matlab.ui.control.Label
        KDSpinner                   matlab.ui.control.Spinner
        PengaturanSumbuRoll         matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        KPSpinner_2Label            matlab.ui.control.Label
        KPSpinner_2                 matlab.ui.control.Spinner
        KISpinner_2Label            matlab.ui.control.Label
        KISpinner_2                 matlab.ui.control.Spinner
        KDSpinner_2Label            matlab.ui.control.Label
        KDSpinner_2                 matlab.ui.control.Spinner
        Text_Penjelasan_SudutRoll   matlab.ui.control.Label
        PengaturanSumbuPicth        matlab.ui.container.Panel
        GridLayout2_2               matlab.ui.container.GridLayout
        KPSpinner_3Label            matlab.ui.control.Label
        KPSpinner_3                 matlab.ui.control.Spinner
        KISpinner_3Label            matlab.ui.control.Label
        KISpinner_3                 matlab.ui.control.Spinner
        KDSpinner_3Label            matlab.ui.control.Label
        KDSpinner_3                 matlab.ui.control.Spinner
        Text_Penjelasan_SudutPitch  matlab.ui.control.Label
        PengaturanSumbuYaw          matlab.ui.container.Panel
        GridLayout2_3               matlab.ui.container.GridLayout
        KPSpinner_4Label            matlab.ui.control.Label
        KPSpinner_4                 matlab.ui.control.Spinner
        KISpinner_4Label            matlab.ui.control.Label
        KISpinner_4                 matlab.ui.control.Spinner
        KDSpinner_4Label            matlab.ui.control.Label
        KDSpinner_4                 matlab.ui.control.Spinner
        Text_Penjelasan_SudutYaw    matlab.ui.control.Label
        Text_Penjelasan_Kecepatan   matlab.ui.control.Label
        PengaturanKecepatan         matlab.ui.container.Panel
        GridLayout2_4               matlab.ui.container.GridLayout
        KPSpinner_5Label            matlab.ui.control.Label
        KPSpinner_5                 matlab.ui.control.Spinner
        KISpinner_5Label            matlab.ui.control.Label
        KISpinner_5                 matlab.ui.control.Spinner
        KDSpinner_5Label            matlab.ui.control.Label
        KDSpinner_5                 matlab.ui.control.Spinner
        GambarMod1                  matlab.ui.control.Image
        GambarMod2                  matlab.ui.control.Image
        GambarPers1                 matlab.ui.control.Image
        GambarPers2                 matlab.ui.control.Image
        GambarPers3                 matlab.ui.control.Image
        GambarPers4                 matlab.ui.control.Image
        Tricopter                   matlab.ui.control.Image
        Fixedwing                   matlab.ui.control.Image
        BlokKetinggian              matlab.ui.control.Image
        BlokRoll                    matlab.ui.control.Image
        BlokPicth                   matlab.ui.control.Image
        BlokYaw                     matlab.ui.control.Image
        BlokKecepatan               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PilihDropDown
        function PilihDropDownValueChanged(app, event)
            if (strcmp(app.PilihDropDown.Value, 'PENDAHULUAN'))
                app.Text_Pendahuluan.Visible='on';
                app.Text_Persamaan.Visible='off';
                app.Text_Spesifikasi.Visible='off';
                app.Text_Penjelasan_Kecepatan.Visible='off';
                app.Text_Penjelasan_Ketinggian.Visible='off';
                app.Text_Penjelasan_SudutPitch.Visible='off';
                app.Text_Penjelasan_SudutRoll.Visible='off';
                app.Text_Penjelasan_SudutYaw.Visible='off';
                app.MulaiAll.Visible='off';
                app.Grafik.visible='off';
                app.
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SISTEMPENGATURANDANPEMANDUANUAVHYBRIDLabel = uilabel(app.UIFigure);
            app.SISTEMPENGATURANDANPEMANDUANUAVHYBRIDLabel.Text = {'SISTEM PENGATURAN DAN PEMANDUAN UAV HYBRID'; ''};
            app.LABORATORIUMSISTEMDANSIBERNETIKALabel = uilabel(app.UIFigure);
            app.LABORATORIUMSISTEMDANSIBERNETIKALabel.Text = 'LABORATORIUM SISTEM DAN SIBERNETIKA';
            app.PilihDropDownLabel = uilabel(app.UIFigure);
            app.PilihDropDownLabel.Text = 'Pilih: ';
            app.PilihDropDown = uidropdown(app.UIFigure);
            app.PilihDropDown.Items = {'PENDAHULUAN', 'SPESIFIKASI UAV HYBRID', 'PERSAMAAN UAV HYBRID', 'SISTEM PENGATURAN KETINGGIAN', 'PENGATURAN SUMBU ROLL', 'PENGATURAN SUMBU PICTH', 'PENGATURAN SUMBU YAW', 'SISTEM PENGATURAN KECEPATAN'};
            app.PilihDropDown.ValueChangedFcn = createCallbackFcn(app, @PilihDropDownValueChanged, true);
            app.PilihDropDown.Value = 'PENDAHULUAN';
            app.Text_Pendahuluan = uilabel(app.UIFigure);
            app.Text_Pendahuluan.Text = {'  Unmanned Aerial Vehicle (UAV) atau pada umumnya '; 'dikenal dengan Drone ataupun pesawat tanpa awak ini'; 'awalnya digunakan untuk memata-matai musuh, '; 'kemudian berkembang dan menjadi drone-drone yang kita '; 'kenal saat ini. Drone saat ini sedang sangat digemari '; 'dikalangan public, berdasarkan fisiknya, tipe drone '; 'dibedakan menjadi 2, yaitu Drone Multirotor dan '; 'Drone Fixed Wing.'; '  Sedangkan untuk Tipe Drone Fixed-Wing berdasarkan '; 'cara Take-Off dan Landing dibedakan menjadi 2, yaitu '; 'Drone Fixed Wing standard dan drone Vertical Take-Off '; 'dan Landing (VTOL) '; '  Tipe Drone Fixed-Wing pada umumnya memiliki durasi '; 'terbang yang lebih lama dibandingkan dengan drone '; 'multirotor, hal ini dikarenakan jumlah motor, power yang '; 'digunakan dan juga fungsinya itu sendiri. Drone multirotor '; 'dapat mengangkat beban lebih berat dibandingkan dengan '; 'drone Fixed-Wing karena jumlah motor pada drone '; 'multirotor lebih banyak. Pada saat ini drone multirotor '; 'sudah dikembangkan untuk membawa paket ke '; 'daerah-daerah yang sulit dijangkau contohnya untuk '; 'membawa obat-obatan atau membawa makanan dan '; 'barang untuk keadaan darurat ke daerah yang kena '; 'bencana alam atau yang sedang membutuhkan bantuan '; 'dan sebagainya.'};
            app.Text_Spesifikasi = uilabel(app.UIFigure);
            app.Text_Spesifikasi.Visible = 'off';
            app.Text_Spesifikasi.Text = {'  Untuk Spesifikasi pada UAV Hybrid ini kita '; 'menggunakan beberapa parameter dalam '; 'spesifikasi: '; 'Wing span               = 1.27 m'; 'Wing surface area   = 0.3097 m2'; 'Maincord                 =  0.25 m'; 'Massa                     = 1.959 kg'; ''; 'Dengan Frame Koordinat: '; 'Inertial frame: Frame ini adalah frame '; 'dari bumi dan biasa disebut North-East-Down (NED) frame.'; 'Vehicle frame: Pusat dari vehicle frame adalah pusat massa '; 'UAV. Sumbu pada vehicle frame sesuai dengan sumbu '; 'inertial frame.'; 'Vehicle-1 frame: Pada frame vehicle-1 adalah rotasi UAV '; 'pada sudut (Ψ) dengan menggunakan kaidah tangan kanan.'; 'Vehicle-2 frame: Pada frame vehicle-2 adalah rotasi UAV '; 'pada sudut (θ) dengan menggunakan kaidah tangan kanan'; 'Body frame: Rotasi body frame terhadap sudut roll (φ) dengan'; 'menggunakan kaidah tangan kanan.'; ''; 'Untuk Frame Koordinat Tricopter:'; 'Pada konfigurasi enam rotor pada trikopter, '; 'terdapat frame bumi dan frame body.'};
            app.Text_Persamaan = uilabel(app.UIFigure);
            app.Text_Persamaan.Visible = 'off';
            app.Text_Persamaan.Text = {'  Untuk persamaan atau model yang akan digunakan '; 'kita dalam menjalankan simulasi ini adalah sebagai'; 'berikut: '; '(Dua dari atas adalah model dari fixed wing dan dua dari bawah'; 'adalah model dari tricopter)'};
            app.Grafik = uiaxes(app.UIFigure);
            title(app.Grafik, 'Title')
            xlabel(app.Grafik, 'X')
            ylabel(app.Grafik, 'Y')
            app.Grafik.Visible = 'off';
            app.MulaiAll = uibutton(app.UIFigure, 'push');
            app.MulaiAll.Visible = 'off';
            app.MulaiAll.Text = 'Mulai';
            app.Text_Penjelasan_Ketinggian = uilabel(app.UIFigure);
            app.Text_Penjelasan_Ketinggian.Visible = 'off';
            app.Text_Penjelasan_Ketinggian.Text = {'   Sekarang untuk mengatur ketinggian pada UAV Hybrid ini, '; 'yang bertugas untuk menjalankan adalah tricopter maka '; 'pada saat take-off tricopter diatur untuk ketinggian yang '; 'diinginkan. Maka untuk pengaturannya di lakukan di '; 'bagian ini. '};
            app.PengaturanKetinggian = uipanel(app.UIFigure);
            app.PengaturanKetinggian.Title = 'PID Controller Ketinggian';
            app.PengaturanKetinggian.Visible = 'off';
            app.GridLayout = uigridlayout(app.PengaturanKetinggian);
            app.KPSpinnerLabel = uilabel(app.GridLayout);
            app.KPSpinnerLabel.Text = 'KP';
            app.KPSpinner = uispinner(app.GridLayout);
            app.KISpinnerLabel = uilabel(app.GridLayout);
            app.KISpinnerLabel.Text = 'KI';
            app.KISpinner = uispinner(app.GridLayout);
            app.KDSpinnerLabel = uilabel(app.GridLayout);
            app.KDSpinnerLabel.Text = 'KD';
            app.KDSpinner = uispinner(app.GridLayout);
            app.PengaturanSumbuRoll = uipanel(app.UIFigure);
            app.PengaturanSumbuRoll.Title = 'Pengaturan Sumbu Roll';
            app.PengaturanSumbuRoll.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.PengaturanSumbuRoll);
            app.KPSpinner_2Label = uilabel(app.GridLayout2);
            app.KPSpinner_2Label.Text = 'KP';
            app.KPSpinner_2 = uispinner(app.GridLayout2);
            app.KISpinner_2Label = uilabel(app.GridLayout2);
            app.KISpinner_2Label.Text = 'KI';
            app.KISpinner_2 = uispinner(app.GridLayout2);
            app.KDSpinner_2Label = uilabel(app.GridLayout2);
            app.KDSpinner_2Label.Text = 'KD';
            app.KDSpinner_2 = uispinner(app.GridLayout2);
            app.Text_Penjelasan_SudutRoll = uilabel(app.UIFigure);
            app.Text_Penjelasan_SudutRoll.Visible = 'off';
            app.Text_Penjelasan_SudutRoll.Text = {'  Pada UAV Hybrid ini memiliki 3 sumbu rotasi (Roll, Pitch,'; 'dan Yaw) pada tahapan disini kita ditugaskan untuk '; 'mengatur sumbu Roll dari  UAV pada saat terbang nanti.'; 'Berikut adalah blok diagram dan pengaturannya.'};
            app.PengaturanSumbuPicth = uipanel(app.UIFigure);
            app.PengaturanSumbuPicth.Title = 'Pengaturan Sumbu Picth';
            app.PengaturanSumbuPicth.Visible = 'off';
            app.GridLayout2_2 = uigridlayout(app.PengaturanSumbuPicth);
            app.KPSpinner_3Label = uilabel(app.GridLayout2_2);
            app.KPSpinner_3Label.Text = 'KP';
            app.KPSpinner_3 = uispinner(app.GridLayout2_2);
            app.KISpinner_3Label = uilabel(app.GridLayout2_2);
            app.KISpinner_3Label.Text = 'KI';
            app.KISpinner_3 = uispinner(app.GridLayout2_2);
            app.KDSpinner_3Label = uilabel(app.GridLayout2_2);
            app.KDSpinner_3Label.Text = 'KD';
            app.KDSpinner_3 = uispinner(app.GridLayout2_2);
            app.Text_Penjelasan_SudutPitch = uilabel(app.UIFigure);
            app.Text_Penjelasan_SudutPitch.Visible = 'off';
            app.Text_Penjelasan_SudutPitch.Text = {'  Pada UAV Hybrid ini memiliki 3 sumbu rotasi (Roll, Pitch,'; 'dan Yaw) pada tahapan disini kita ditugaskan untuk '; 'mengatur sumbu Picth dari UAV pada saat terbang nanti.'; 'Berikut adalah blok diagram dan pengaturannya.'};
            app.PengaturanSumbuYaw = uipanel(app.UIFigure);
            app.PengaturanSumbuYaw.Title = 'Pengaturan Sumbu Yaw';
            app.PengaturanSumbuYaw.Visible = 'off';
            app.GridLayout2_3 = uigridlayout(app.PengaturanSumbuYaw);
            app.KPSpinner_4Label = uilabel(app.GridLayout2_3);
            app.KPSpinner_4Label.Text = 'KP';
            app.KPSpinner_4 = uispinner(app.GridLayout2_3);
            app.KISpinner_4Label = uilabel(app.GridLayout2_3);
            app.KISpinner_4Label.Text = 'KI';
            app.KISpinner_4 = uispinner(app.GridLayout2_3);
            app.KDSpinner_4Label = uilabel(app.GridLayout2_3);
            app.KDSpinner_4Label.Text = 'KD';
            app.KDSpinner_4 = uispinner(app.GridLayout2_3);
            app.Text_Penjelasan_SudutYaw = uilabel(app.UIFigure);
            app.Text_Penjelasan_SudutYaw.Visible = 'off';
            app.Text_Penjelasan_SudutYaw.Text = {'  Pada UAV Hybrid ini memiliki 3 sumbu rotasi (Roll, Pitch,'; 'dan Yaw) pada tahapan disini kita ditugaskan untuk '; 'mengatur sumbu Yaw dari UAV pada saat terbang nanti.'; 'Berikut adalah blok diagram dan pengaturannya.'};
            app.Text_Penjelasan_Kecepatan = uilabel(app.UIFigure);
            app.Text_Penjelasan_Kecepatan.Visible = 'off';
            app.Text_Penjelasan_Kecepatan.Text = {'   Sekarang untuk mengatur kecepatan pada UAV Hybrid ini, '; 'yang bertugas untuk menjalankan adalah fixed-wing maka '; 'pada saat terbang fixed-wing diatur untuk kecepatan yang '; 'diinginkan. Maka untuk pengaturannya di lakukan di '; 'bagian ini. '};
            app.PengaturanKecepatan = uipanel(app.UIFigure);
            app.PengaturanKecepatan.Title = 'Pengaturan Kecepatan';
            app.PengaturanKecepatan.Visible = 'off';
            app.GridLayout2_4 = uigridlayout(app.PengaturanKecepatan);
            app.KPSpinner_5Label = uilabel(app.GridLayout2_4);
            app.KPSpinner_5Label.Text = 'KP';
            app.KPSpinner_5 = uispinner(app.GridLayout2_4);
            app.KISpinner_5Label = uilabel(app.GridLayout2_4);
            app.KISpinner_5Label.Text = 'KI';
            app.KISpinner_5 = uispinner(app.GridLayout2_4);
            app.KDSpinner_5Label = uilabel(app.GridLayout2_4);
            app.KDSpinner_5Label.Text = 'KD';
            app.KDSpinner_5 = uispinner(app.GridLayout2_4);
            app.GambarMod1 = uiimage(app.UIFigure);
            app.GambarMod2 = uiimage(app.UIFigure);
            app.GambarPers1 = uiimage(app.UIFigure);
            app.GambarPers1.Visible = 'off';
            app.GambarPers2 = uiimage(app.UIFigure);
            app.GambarPers2.Visible = 'off';
            app.GambarPers3 = uiimage(app.UIFigure);
            app.GambarPers3.Visible = 'off';
            app.GambarPers4 = uiimage(app.UIFigure);
            app.GambarPers4.Visible = 'off';
            app.Tricopter = uiimage(app.UIFigure);
            app.Tricopter.Visible = 'off';
            app.Fixedwing = uiimage(app.UIFigure);
            app.Fixedwing.Visible = 'off';
            app.BlokKetinggian = uiimage(app.UIFigure);
            app.BlokKetinggian.Visible = 'off';
            app.BlokRoll = uiimage(app.UIFigure);
            app.BlokRoll.Visible = 'off';
            app.BlokPicth = uiimage(app.UIFigure);
            app.BlokPicth.Visible = 'off';
            app.BlokYaw = uiimage(app.UIFigure);
            app.BlokYaw.Visible = 'off';
            app.BlokKecepatan = uiimage(app.UIFigure);
            app.BlokKecepatan.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UAVHybridFix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end